classdef AOMontagingGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        CloseMenu                       matlab.ui.container.Menu
        PreferencesMenu                 matlab.ui.container.Menu
        InputSettingsMenu               matlab.ui.container.Menu
        MontagedSectionsDropDown        matlab.ui.control.DropDown
        MontagedSectionsDropDownLabel   matlab.ui.control.Label
        InputImagesDropDown             matlab.ui.control.DropDown
        InputImagesDropDownLabel        matlab.ui.control.Label
        SelectingDataLabel              matlab.ui.control.Label
        MontageButton                   matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        SingleTimepointTab              matlab.ui.container.Tab
        ExportPhotoShop                 matlab.ui.control.CheckBox
        FeatureTypeDropDown             matlab.ui.control.DropDown
        FeatureTypeDropDownLabel        matlab.ui.control.Label
        TransformationTypeDropDown      matlab.ui.control.DropDown
        TransformationTypeDropDownLabel  matlab.ui.control.Label
        MontageTypeDropDown             matlab.ui.control.DropDown
        MontageTypeDropDownLabel        matlab.ui.control.Label
        OutputFolderField               matlab.ui.control.EditField
        PositionFileField               matlab.ui.control.EditField
        ImageFolderField                matlab.ui.control.EditField
        OutputFolderButton              matlab.ui.control.Button
        PositionFileButton              matlab.ui.control.Button
        ImageFolderButton               matlab.ui.control.Button
        LongitudinalTab                 matlab.ui.container.Tab
        FeatureTypeDropDownFollow       matlab.ui.control.DropDown
        FeatureTypeDropDown_3Label      matlab.ui.control.Label
        TransformationTypeDropDownFollow  matlab.ui.control.DropDown
        TransformationTypeDropDown_3Label  matlab.ui.control.Label
        UseExistingMontageLabel         matlab.ui.control.Label
        UseExistingBaseCheckBox         matlab.ui.control.CheckBox
        FeatureTypeDropDownBase         matlab.ui.control.DropDown
        FeatureTypeDropDown_2Label      matlab.ui.control.Label
        TransformationTypeDropDownBase  matlab.ui.control.DropDown
        TransformationTypeDropDown_2Label  matlab.ui.control.Label
        ExistingMontageField            matlab.ui.control.EditField
        ExistingMontageButton           matlab.ui.control.Button
        FollowupLabel                   matlab.ui.control.Label
        OutputFolderFieldLong           matlab.ui.control.EditField
        PositionFileFieldFollow         matlab.ui.control.EditField
        ImageFolderFieldFollow          matlab.ui.control.EditField
        OutputFolderButtonLong          matlab.ui.control.Button
        PositionFileButtonFollow        matlab.ui.control.Button
        ImageFolderButtonFollow         matlab.ui.control.Button
        BaselineLabel                   matlab.ui.control.Label
        PositionFileFieldBase           matlab.ui.control.EditField
        ImageFolderFieldBase            matlab.ui.control.EditField
        PositionFileButtonBase          matlab.ui.control.Button
        ImageFolderButtonBase           matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        %Montage Variables
        DeviceMode % Type of AO Device
        ModalitiesInfo %Table with modality information and search terms
        InputExt %File Extension
        %Single timepoint variables
        ImageFolder % Folder With All Input imagesDescription
        MontagePreviewFiles% Montage Preview Results
        OutputFolder % Output Folder Directory
        PositionFile %CSV file with position and scaling information
        ImageNames % List of all Images in Image Folder
        %Longitudinal
        %%baseline variables
        ImageFolderBase % Folder With All Input imagesDescription
        PositionFileBase %CSV file with position and scaling information
        ExistingMontageBase %Existing montage for baseline
        ImageNamesBase % List of all Images in Image Folder
        
        %%followup variables
        ImageFolderFollow % Folder With All Input imagesDescription
        PositionFileFollow %CSV file with position and scaling information
        ImageNamesFollow % List of all Images in Image Folder
        
        %%Shared variables
        ImageFoldersLong %All Base folders combined
        MontagePreviewFilesLong% Montage Preview Results
        OutputFolderLong % Output Folder Directory
        
    end
    
    methods (Access = private)
        
        function ImageNames = LoadImages(app,ImageFolder)
            
            Allfiles = dir(fullfile(ImageFolder,'*.tif'));
            Allfiles = {Allfiles.name};
            %Use current identifiers to locate all images
            ImageNames =[];
            dataSummary = cell(1,1);
            dataSummary{1} = 'Input Complete!';
            dataSummary{2} = 'Summary:';
            
            if (app.DeviceMode==1)%Multi-modal
                for m = 1:size(app.ModalitiesInfo,1)
                    if (~isempty(app.ModalitiesInfo{m,2}))%check it's not empty
                        found = sort(Allfiles(~cellfun(@isempty, strfind(Allfiles, app.ModalitiesInfo{m,2}))));
                        ImageNames = [ImageNames, found];%search
                        dataSummary{end+1} =[num2str(size(found,2)),' ',char(app.ModalitiesInfo{m,1}),' image(s) found.'];
                    end
                end
                
            elseif (app.DeviceMode==2)%Canon
                found = sort(Allfiles(cellfun(@(s) strcmp(s(1:4),'206-'), Allfiles )));
                ImageNames = [ImageNames, found];%search
                dataSummary{end+1} =[num2str(size(found,2)),' image(s) found.'];
            end
            dataSummary{end+1} = '';
            dataSummary{end+1} = 'Note: You can modify how to search for each';
            dataSummary{end+1} = 'modality under Preferences->Input Settings.';
            
            app.SelectingDataLabel.Text = dataSummary;
        end
        
        
        function StartSingleMontage(app)
            
            %Get Transformation Type
            TransType = str2num(app.TransformationTypeDropDown.Value);%0=Translation only, 1=Rigid, 2=Rigid+Scale
            
            %New Montage Or Append to Existing?
            AppendToExisting=0;
            MontageSave=[];
            switch app.MontageTypeDropDown.Value
                case 0
                    % If new check for existing save file exists
                    if(exist(fullfile(app.OutputFolder, 'AOMontageSave.mat'), 'file'))
                        choice = questdlg('AOMontageSave.mat file found in output folder. Overwrite existing montage?','Warrning');
                        if(~isequal(choice,'Yes'))
                            return;
                        end
                    end
                    AppendToExisting=0;
                case 1
                    %If appending check for existing save file
                    if(~exist(fullfile(app.OutputFolder, 'AOMontageSave.mat'), 'file'))
                        choice = questdlg('Appending Failed: AOMontageSave.mat file not found in output folder. Continue as New?','Error');
                        if(isequal(choice,'Yes'))
                            AppendToExisting = 0;
                        else
                            return;
                        end
                    else
                        MontageSave = fullfile(app.OutputFolder, 'AOMontageSave.mat');
                        AppendToExisting=1;
                    end
            end
            
            %read filename substrings to search for different modalities
            
            dataSummary = cell(1,1);
            dataSummary{1} = 'Starting Montage...';
            app.SelectingDataLabel.Text = dataSummary;
            
            tic
            app.MontagePreviewFiles = AOMosiacAllMultiModal(app.ImageFolder, app.PositionFile, ...
                app.OutputFolder, app.DeviceMode, ...
                app.ModalitiesInfo(:,2), TransType,AppendToExisting, ...
                MontageSave, app.ExportPhotoShop.Value);
            runtime = toc;
            if(~isempty(app.MontagePreviewFiles))
                dataSummary{2} = 'Montage Complete!';
                dataSummary{3} = ['Runtime: ' num2str(runtime) ' seconds'];
                dataSummary{4} = ['Number of breaks found in montage: ' num2str(max(size(app.MontagePreviewFiles,1)-2,0))];
                dataSummary{5} = 'Preview the montage below.';
                
                app.MontagedSectionsDropDown.Items = app.MontagePreviewFiles(:);
                a=imread(fullfile(app.OutputFolder,app.MontagedSectionsDropDown.Value));
                imshow(a(:,:,1),'Parent',app.UIAxes);
                
            else
                dataSummary{2} = 'ERROR: Montaging failed. Check log.';
            end
            app.SelectingDataLabel.Text = dataSummary;
        end
        
        function StartLongMontage(app)
            
            if(app.UseExistingBaseCheckBox.Value)
                ExistingMontageFile=app.ExistingMontageBase;
            else
                ExistingMontageFile=[];
            end
            
            BaseTransType= str2num(app.TransformationTypeDropDownBase.Value);
            BaseFeatureType =str2num(app.FeatureTypeDropDownBase.Value);
            FollowTransType= str2num(app.TransformationTypeDropDownFollow.Value);
            FollowFeatureType=str2num(app.FeatureTypeDropDownFollow.Value);
            AOMontageLongitudinal(app.ImageFolderBase, app.PositionFileBase, app.ImageFolderFollow, app.PositionFileFollow,...
                app.OutputFolderLong, app.DeviceMode, app.ModalitiesInfo(:,2), BaseTransType,BaseFeatureType,...
                FollowTransType,FollowFeatureType,ExistingMontageFile)
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.OutputFolder=[];
            app.MontagePreviewFiles=[];
            app.PositionFile=[];
            app.ImageFolder=[];
            app.ImageNames=[];
            app.ModalitiesInfo = {'Confocal' 'confocal';
                'Split Detection' 'split_det';
                'Dark Field' 'avg';
                'Modality 4' '';
                'Modality 5' '';};
            app.InputExt = 1;
            app.DeviceMode = 1;
            
            %add path and setup vl_feat
            currentFile = mfilename('fullpath');
            [currentFileLoc,name,ext] = fileparts(currentFile);
            genpath(fullfile(currentFileLoc,'SupportFunctions'));
            addpath(genpath(fullfile(currentFileLoc,'SupportFunctions')));
            vl_setup;
            
            % If we've set up photoshop, then enable and check the export to photoshop
            % buttons.
            if exist('psnewdoc')
                app.ExportPhotoShop.Enable;
                app.ExportPhotoShop.Value = true;
            end
            
            % Check this version of AO Montaging against git.
            fid = fopen(fullfile(getparent(which(mfilename)),'.VERSION'),'r');
            if fid ~= -1
                thisver = fscanf(fid,'%s');
                fclose(fid);
                git_version_check( 'BrainardLab','AOAutomontaging', thisver )
            else
                warning('Failed to detect .VERSION file, unable to determine if running the newest version.')
            end
            
            axis(app.UIAxes, 'image');
            
        end

        % Button pushed function: ImageFolderButton
        function ImageFolderButtonPushed(app, event)
            selectedDir=uigetdir(app.ImageFolder);
            drawnow;
            figure(app.UIFigure)
            if(selectedDir == 0)
                return
            end
            
            app.ImageFolder = selectedDir;
            app.ImageFolderField.Value =  selectedDir;
            app.ImageFolderField.Tooltip =  selectedDir;
            
            app.ImageNames=LoadImages(app,app.ImageFolder);
            
            
            if(isempty(app.ImageNames))
                return;
            end
            
            app.InputImagesDropDown.Items = app.ImageNames;
            app.InputImagesDropDown.ItemsData = num2cell(1:length(app.ImageNames));
            a=imread(fullfile(app.ImageFolder,app.InputImagesDropDown.Items{1}));
            imshow(a(:,:,1),'Parent',app.UIAxes);
        end

        % Button pushed function: PositionFileButton
        function PositionFileButtonPushed(app, event)
            defaultfolder = app.ImageFolder;
            %[FileName,PathName] = uigetfile({'*.xlsx', 'Legacy LUT Format (*.xlsx)';'*.csv', 'LUT Files (*.csv)'}, ...
            %    'Select LUT file:', defaultfolder);
            [FileName,PathName] = uigetfile({'*.*'}, ...
                'Select LUT file:', defaultfolder);
            drawnow;
            figure(app.UIFigure)
            if(FileName == 0)
                return
            end
            app.PositionFile = fullfile(PathName,FileName);
            app.PositionFileField.Value = app.PositionFile;
            app.PositionFileField.Tooltip = app.PositionFile;
        end

        % Button pushed function: OutputFolderButton
        function OutputFolderButtonPushed(app, event)
            selectedDir=uigetdir(app.ImageFolder);
            drawnow;
            figure(app.UIFigure)
            if(selectedDir == 0)
                return
            end
            app.OutputFolder = selectedDir;
            app.OutputFolderField.Value =  selectedDir;
            app.OutputFolderField.Tooltip =  selectedDir;
        end

        % Button pushed function: MontageButton
        function MontageButtonPushed(app, event)

            if(strcmpi(app.TabGroup.SelectedTab.Title,'Single Timepoint'))
                StartSingleMontage(app);
            else
                StartLongMontage(app);
            end
            
        end

        % Value changed function: InputImagesDropDown
        function InputImagesDropDownValueChanged(app, event)
            index = app.InputImagesDropDown.Value;
            if(strcmpi(app.TabGroup.SelectedTab.Title,'Single Timepoint'))
                a=imread(fullfile(app.ImageFolder,app.InputImagesDropDown.Items{index}));
            else
                a=imread(fullfile(app.ImageFoldersLong{index},app.InputImagesDropDown.Items{index}));
            end
            imshow(a(:,:,1),'Parent',app.UIAxes);
        end

        % Value changed function: MontagedSectionsDropDown
        function MontagedSectionsDropDownValueChanged(app, event)
            a=imread(fullfile(app.OutputFolder,app.MontagedSectionsDropDown.Value));
            imshow(a(:,:,1),'Parent',app.UIAxes);
        end

        % Button pushed function: ImageFolderButtonBase
        function ImageFolderButtonBasePushed(app, event)
            selectedDir=uigetdir(app.ImageFolderBase);
            drawnow;
            figure(app.UIFigure)
            if(selectedDir == 0)
                return
            end
            
            app.ImageFolderBase = selectedDir;
            app.ImageFolderFieldBase.Value =  selectedDir;
            app.ImageFolderFieldBase.Tooltip =  selectedDir;
            
            app.ImageNamesBase = LoadImages(app,app.ImageFolderBase);
            
            if(isempty([app.ImageNamesBase  app.ImageNamesFollow]))
                return;
            end
            
            app.InputImagesDropDown.Items = [app.ImageNamesBase  app.ImageNamesFollow];
            app.InputImagesDropDown.ItemsData = num2cell(1:(length(app.ImageNamesBase)+length(app.ImageNamesFollow)));
            app.ImageFoldersLong = [repmat({app.ImageFolderBase},1,length(app.ImageNamesBase)) ...
                repmat({app.ImageFolderFollow},1,length(app.ImageNamesFollow))];
            a=imread(fullfile(app.ImageFoldersLong{1},app.InputImagesDropDown.Items{1}));
            
            imshow(a(:,:,1),'Parent',app.UIAxes);
            
        end

        % Value changed function: UseExistingBaseCheckBox
        function UseExistingBaseCheckBoxValueChanged(app, event)
            if(app.UseExistingBaseCheckBox.Value)
                app.ExistingMontageButton.Enable=1;
            else
                app.ExistingMontageButton.Enable=0;
                app.ExistingMontageField.Value = '';
                app.ExistingMontageField.Tooltip = '';
            end
        end

        % Button pushed function: PositionFileButtonBase
        function PositionFileButtonBasePushed(app, event)
            defaultfolder = app.ImageFolderBase;
            [FileName,PathName] = uigetfile({ '*.csv', 'LUT Files (*.csv)'; '*.xlsx', 'Legacy LUT Format (*.xlsx)' }, ...
                'Select LUT file:', defaultfolder);
            drawnow;
            figure(app.UIFigure)
            if(FileName == 0)
                return
            end
            app.PositionFileBase = fullfile(PathName,FileName);
            app.PositionFileFieldBase.Value = app.PositionFileBase;
            app.PositionFileFieldBase.Tooltip = app.PositionFileBase;
        end

        % Button pushed function: PositionFileButtonFollow
        function PositionFileButtonFollowPushed(app, event)
            defaultfolder = app.ImageFolderFollow;
            [FileName,PathName] = uigetfile({ '*.csv', 'LUT Files (*.csv)'; '*.xlsx', 'Legacy LUT Format (*.xlsx)' }, ...
                'Select LUT file:', defaultfolder);
            drawnow;
            figure(app.UIFigure)
            if(FileName == 0)
                return
            end
            app.PositionFileFollow = fullfile(PathName,FileName);
            app.PositionFileFieldFollow.Value = app.PositionFileFollow;
            app.PositionFileFieldFollow.Tooltip = app.PositionFileFollow;
        end

        % Button pushed function: OutputFolderButtonLong
        function OutputFolderButtonLongPushed(app, event)
            selectedDir=uigetdir(app.ImageFolderBase);
            drawnow;
            figure(app.UIFigure)
            if(selectedDir == 0)
                return
            end
            app.OutputFolderLong = selectedDir;
            app.OutputFolderFieldLong.Value =  selectedDir;
            app.OutputFolderFieldLong.Tooltip =  selectedDir;
        end

        % Button pushed function: ImageFolderButtonFollow
        function ImageFolderButtonFollowPushed(app, event)
            selectedDir=uigetdir(app.ImageFolderFollow);
            drawnow;
            figure(app.UIFigure)
            if(selectedDir == 0)
                return
            end
            
            app.ImageFolderFollow = selectedDir;
            app.ImageFolderFieldFollow.Value =  selectedDir;
            app.ImageFolderFieldFollow.Tooltip =  selectedDir;
            
            app.ImageNamesFollow = LoadImages(app,app.ImageFolderFollow);
            
            if(isempty([app.ImageNamesBase  app.ImageNamesFollow]))
                return;
            end
            app.InputImagesDropDown.Items = [app.ImageNamesBase  app.ImageNamesFollow];
            app.InputImagesDropDown.ItemsData = num2cell(1:(length(app.ImageNamesBase)+length(app.ImageNamesFollow)));
            app.ImageFoldersLong = [repmat({app.ImageFolderBase},1,length(app.ImageNamesBase)) ...
                repmat({app.ImageFolderFollow},1,length(app.ImageNamesFollow))];
            a=imread(fullfile(app.ImageFoldersLong{1},app.InputImagesDropDown.Items{1}));
            imshow(a(:,:,1),'Parent',app.UIAxes);
        end

        % Button pushed function: ExistingMontageButton
        function ExistingMontageButtonPushed(app, event)
            defaultfolder = app.ImageFolderFollow;
            [FileName,PathName] = uigetfile('*.mat', defaultfolder);
            drawnow;
            figure(app.UIFigure)
            if(FileName == 0)
                return
            end
            app.ExistingMontageBase =  fullfile(PathName,FileName);
            app.ExistingMontageField.Value =  app.ExistingMontageBase;
            app.ExistingMontageField.Tooltip =  app.ExistingMontageBase;
        end

        % Menu selected function: InputSettingsMenu
        function InputSettingsMenuSelected(app, event)
            Title = 'Input Settings';
            
            %%%% SETTING DIALOG OPTIONS
            % Options.WindowStyle = 'modal';
            Options.Resize = 'on';
            Options.Interpreter = 'tex';
            Options.CancelButton = 'on';
            Options.ApplyButton = 'off';
            Options.ButtonNames = {'Save','Cancel'}; %<- default names, included here just for illustration
            
            Prompt = {};
            Formats = {};
            DefAns = struct([]);
            
            Prompt(1,:) = {'Filename substrings for identifying each modality:','modalitiesInfo',[]};
            Formats(1,1).type = 'table';
            Formats(1,1).format = {'char', 'char'}; % table (= table in main dialog) / window (= table in separate dialog)
            Formats(1,1).items = {'Modality Name' 'Substring'};
            Formats(1,1).size = [160 110];
            Formats(1,1).margin = 2;
            Formats(1,1).span = [1 1];  % item is 2 field x 1 fields
            Formats(1,1).labelloc = 'topcenter';
            Formats(1,1).unitsloc = 'bottomcenter';
            DefAns(1).modalitiesInfo = app.ModalitiesInfo;
            
            Prompt(2,:) = {'Input images extension:','inputExt',[]};
            Formats(2,1).type = 'list';
            Formats(2,1).style = 'popupmenu';
            Formats(2,1).items = {'.tif','.png'};
            DefAns(1).inputExt = app.InputExt;

            Prompt(3,:) = {'Device:','deviceMode',[]};
            Formats(3,1).type = 'list';
            Formats(3,1).style = 'popupmenu';
            Formats(3,1).items = {'Multi-modal','Canon'};
            DefAns(1).deviceMode = app.DeviceMode;

            
            [Input,Cancelled] = inputsdlg(Prompt,Title,Formats,DefAns,Options);
            
            if(~Cancelled)
                app.ModalitiesInfo=Input.modalitiesInfo;
                app.InputExt=Input.inputExt;
                app.DeviceMode=Input.deviceMode;
            end
        end

        % Menu selected function: CloseMenu
        function CloseMenuSelected(app, event)
            closereq;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.CloseMenu = uimenu(app.FileMenu);
            app.CloseMenu.MenuSelectedFcn = createCallbackFcn(app, @CloseMenuSelected, true);
            app.CloseMenu.Text = 'Close';
            app.PreferencesMenu = uimenu(app.UIFigure);
            app.PreferencesMenu.Text = 'Preferences';
            app.InputSettingsMenu = uimenu(app.PreferencesMenu);
            app.InputSettingsMenu.MenuSelectedFcn = createCallbackFcn(app, @InputSettingsMenuSelected, true);
            app.InputSettingsMenu.Text = 'Input Settings';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.XTickLabel = {'[]'};
            app.UIAxes.YTick = [];
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SingleTimepointTab = uitab(app.TabGroup);
            app.SingleTimepointTab.Title = 'Single Timepoint';
            app.ImageFolderButton = uibutton(app.SingleTimepointTab, 'push');
            app.ImageFolderButton.ButtonPushedFcn = createCallbackFcn(app, @ImageFolderButtonPushed, true);
            app.ImageFolderButton.Text = 'Image Folder';
            app.PositionFileButton = uibutton(app.SingleTimepointTab, 'push');
            app.PositionFileButton.ButtonPushedFcn = createCallbackFcn(app, @PositionFileButtonPushed, true);
            app.PositionFileButton.Text = 'Scaling/Position File';
            app.OutputFolderButton = uibutton(app.SingleTimepointTab, 'push');
            app.OutputFolderButton.ButtonPushedFcn = createCallbackFcn(app, @OutputFolderButtonPushed, true);
            app.OutputFolderButton.Text = {'Output Folder'; ''};
            app.ImageFolderField = uieditfield(app.SingleTimepointTab, 'text');
            app.PositionFileField = uieditfield(app.SingleTimepointTab, 'text');
            app.OutputFolderField = uieditfield(app.SingleTimepointTab, 'text');
            app.MontageTypeDropDownLabel = uilabel(app.SingleTimepointTab);
            app.MontageTypeDropDownLabel.Text = 'Montage Type';
            app.MontageTypeDropDown = uidropdown(app.SingleTimepointTab);
            app.MontageTypeDropDown.Items = {'New', 'Append', ''};
            app.MontageTypeDropDown.ItemsData = {'0', '1'};
            app.MontageTypeDropDown.Value = '0';
            app.TransformationTypeDropDownLabel = uilabel(app.SingleTimepointTab);
            app.TransformationTypeDropDownLabel.Text = 'Transformation Type';
            app.TransformationTypeDropDown = uidropdown(app.SingleTimepointTab);
            app.TransformationTypeDropDown.Items = {'Translation', 'Rigid', 'Rigid+Scale'};
            app.TransformationTypeDropDown.ItemsData = {'0', '1', '2'};
            app.TransformationTypeDropDown.Value = '1';
            app.FeatureTypeDropDownLabel = uilabel(app.SingleTimepointTab);
            app.FeatureTypeDropDownLabel.Text = 'Feature Type';
            app.FeatureTypeDropDown = uidropdown(app.SingleTimepointTab);
            app.FeatureTypeDropDown.Items = {'SIFT', 'Constellation'};
            app.FeatureTypeDropDown.ItemsData = {'0', '1'};
            app.FeatureTypeDropDown.Value = '0';
            app.ExportPhotoShop = uicheckbox(app.SingleTimepointTab);
            app.ExportPhotoShop.Enable = 'off';
            app.ExportPhotoShop.Text = 'Export to Photopshop (CS5+)';
            app.LongitudinalTab = uitab(app.TabGroup);
            app.LongitudinalTab.Title = 'Longitudinal';
            app.ImageFolderButtonBase = uibutton(app.LongitudinalTab, 'push');
            app.ImageFolderButtonBase.ButtonPushedFcn = createCallbackFcn(app, @ImageFolderButtonBasePushed, true);
            app.ImageFolderButtonBase.Text = 'Image Folder';
            app.PositionFileButtonBase = uibutton(app.LongitudinalTab, 'push');
            app.PositionFileButtonBase.ButtonPushedFcn = createCallbackFcn(app, @PositionFileButtonBasePushed, true);
            app.PositionFileButtonBase.Text = 'Scaling/Position File';
            app.ImageFolderFieldBase = uieditfield(app.LongitudinalTab, 'text');
            app.PositionFileFieldBase = uieditfield(app.LongitudinalTab, 'text');
            app.BaselineLabel = uilabel(app.LongitudinalTab);
            app.BaselineLabel.Text = 'Baseline';
            app.ImageFolderButtonFollow = uibutton(app.LongitudinalTab, 'push');
            app.ImageFolderButtonFollow.ButtonPushedFcn = createCallbackFcn(app, @ImageFolderButtonFollowPushed, true);
            app.ImageFolderButtonFollow.Text = 'Image Folder';
            app.PositionFileButtonFollow = uibutton(app.LongitudinalTab, 'push');
            app.PositionFileButtonFollow.ButtonPushedFcn = createCallbackFcn(app, @PositionFileButtonFollowPushed, true);
            app.PositionFileButtonFollow.Text = 'Scaling/Position File';
            app.OutputFolderButtonLong = uibutton(app.LongitudinalTab, 'push');
            app.OutputFolderButtonLong.ButtonPushedFcn = createCallbackFcn(app, @OutputFolderButtonLongPushed, true);
            app.OutputFolderButtonLong.Text = {'Output Folder'; ''};
            app.ImageFolderFieldFollow = uieditfield(app.LongitudinalTab, 'text');
            app.PositionFileFieldFollow = uieditfield(app.LongitudinalTab, 'text');
            app.OutputFolderFieldLong = uieditfield(app.LongitudinalTab, 'text');
            app.FollowupLabel = uilabel(app.LongitudinalTab);
            app.FollowupLabel.Text = 'Followup';
            app.ExistingMontageButton = uibutton(app.LongitudinalTab, 'push');
            app.ExistingMontageButton.ButtonPushedFcn = createCallbackFcn(app, @ExistingMontageButtonPushed, true);
            app.ExistingMontageButton.Enable = 'off';
            app.ExistingMontageButton.Text = 'Existing Montage';
            app.ExistingMontageField = uieditfield(app.LongitudinalTab, 'text');
            app.TransformationTypeDropDown_2Label = uilabel(app.LongitudinalTab);
            app.TransformationTypeDropDown_2Label.Text = 'Transformation Type';
            app.TransformationTypeDropDownBase = uidropdown(app.LongitudinalTab);
            app.TransformationTypeDropDownBase.Items = {'Translation', 'Rigid', 'Rigid+Scale'};
            app.TransformationTypeDropDownBase.ItemsData = {'0', '1', '2'};
            app.TransformationTypeDropDownBase.Value = '1';
            app.FeatureTypeDropDown_2Label = uilabel(app.LongitudinalTab);
            app.FeatureTypeDropDown_2Label.Text = 'Feature Type';
            app.FeatureTypeDropDownBase = uidropdown(app.LongitudinalTab);
            app.FeatureTypeDropDownBase.Items = {'SIFT', 'Constellation', 'SURF'};
            app.FeatureTypeDropDownBase.ItemsData = {'0', '1', '2'};
            app.FeatureTypeDropDownBase.Value = '0';
            app.UseExistingBaseCheckBox = uicheckbox(app.LongitudinalTab);
            app.UseExistingBaseCheckBox.ValueChangedFcn = createCallbackFcn(app, @UseExistingBaseCheckBoxValueChanged, true);
            app.UseExistingBaseCheckBox.Text = '';
            app.UseExistingMontageLabel = uilabel(app.LongitudinalTab);
            app.UseExistingMontageLabel.Text = 'Use Existing Montage';
            app.TransformationTypeDropDown_3Label = uilabel(app.LongitudinalTab);
            app.TransformationTypeDropDown_3Label.Text = 'Transformation Type';
            app.TransformationTypeDropDownFollow = uidropdown(app.LongitudinalTab);
            app.TransformationTypeDropDownFollow.Items = {'Translation', 'Rigid', 'Rigid+Scale'};
            app.TransformationTypeDropDownFollow.ItemsData = {'0', '1', '2'};
            app.TransformationTypeDropDownFollow.Value = '2';
            app.FeatureTypeDropDown_3Label = uilabel(app.LongitudinalTab);
            app.FeatureTypeDropDown_3Label.Text = 'Feature Type';
            app.FeatureTypeDropDownFollow = uidropdown(app.LongitudinalTab);
            app.FeatureTypeDropDownFollow.Items = {'SIFT', 'Constellation', 'SURF'};
            app.FeatureTypeDropDownFollow.ItemsData = {'0', '1', '2'};
            app.FeatureTypeDropDownFollow.Value = '1';
            app.MontageButton = uibutton(app.UIFigure, 'push');
            app.MontageButton.ButtonPushedFcn = createCallbackFcn(app, @MontageButtonPushed, true);
            app.MontageButton.Text = 'Montage';
            app.SelectingDataLabel = uilabel(app.UIFigure);
            app.SelectingDataLabel.Text = {'Instructions:'; '1) Select Input Image Folder'; '2) Select Scale/Position File'; '3) Select Output Folder'; '4) Push "Montage"'};
            app.InputImagesDropDownLabel = uilabel(app.UIFigure);
            app.InputImagesDropDownLabel.Text = 'Input Images';
            app.InputImagesDropDown = uidropdown(app.UIFigure);
            app.InputImagesDropDown.Items = {'Empty'};
            app.InputImagesDropDown.ValueChangedFcn = createCallbackFcn(app, @InputImagesDropDownValueChanged, true);
            app.InputImagesDropDown.Value = 'Empty';
            app.MontagedSectionsDropDownLabel = uilabel(app.UIFigure);
            app.MontagedSectionsDropDownLabel.Text = 'Montaged Sections';
            app.MontagedSectionsDropDown = uidropdown(app.UIFigure);
            app.MontagedSectionsDropDown.Items = {'Empty'};
            app.MontagedSectionsDropDown.ValueChangedFcn = createCallbackFcn(app, @MontagedSectionsDropDownValueChanged, true);
            app.MontagedSectionsDropDown.Value = 'Empty';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AOMontagingGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end